classdef AAA_Iter5_Save < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        GridLayout       matlab.ui.container.GridLayout
        Panel            matlab.ui.container.Panel
        GridLayout2      matlab.ui.container.GridLayout
        SAVEDATAButton   matlab.ui.control.Button
        ENTERTITLELabel  matlab.ui.control.Label
        EditField        matlab.ui.control.EditField
    end

    
    properties (Access = private)
        AAA_Iter5_app; % Main App
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.AAA_Iter5_app = caller; 

            screensize = get(groot, 'ScreenSize');

            % Calculate the new position for the figure
            appwidth = 500;
            appheight = 250;
            appx = (screensize(3) - appwidth) / 2;
            appy = (screensize(4) - appheight) / 2;

            % Set the new position for the figure
            set(app.UIFigure, 'Position', [appx, appy, appwidth, appheight]);
        end

        % Button pushed function: SAVEDATAButton
        function SAVEDATAButtonPushed(app, event)
            title = app.EditField.Value; 
            tab_value = app.AAA_Iter5_app.stored_tab_data.tab_number;
            progress_bar = uiprogressdlg(app.UIFigure, 'Title', 'B&W Active Absorber App', 'Message', 'Saving Data', 'Indeterminate','on'); 
            app.AAA_Iter5_app.stored_tab_data(tab_value).SAVE_MAT_FILE(title);

            pause(1);
            close(progress_bar); 
            delete(app); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.EditField = uieditfield(app.GridLayout2, 'text');
            app.ENTERTITLELabel = uilabel(app.GridLayout2);
            app.ENTERTITLELabel.Text = 'ENTER TITLE: ';
            app.SAVEDATAButton = uibutton(app.GridLayout2, 'push');
            app.SAVEDATAButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEDATAButtonPushed, true);
            app.SAVEDATAButton.Text = 'SAVE DATA';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AAA_Iter5_Save(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end